classdef SEMESTRALNI_PRACE_NA_MATLAB_Dominik_Tomasek < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        SPUSTITButton    matlab.ui.control.Button
        ULOITDATAButton  matlab.ui.control.Button
        NATIDATAButton   matlab.ui.control.Button
        KONECButton      matlab.ui.control.Button
        SonarpomocultrazvukovhosenzoruPanel  matlab.ui.container.Panel
        Image            matlab.ui.control.Image
        UITable          matlab.ui.control.Table
    end

    
    properties (Access = private)
        Property % Description
a;
ultrasonic_sensor
servo_motor
kk
table 
Paxx
table1
Novyobrazek
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.a = arduino('COM4', 'Uno', 'Libraries', {'Servo','Ultrasonic'});
app.ultrasonic_sensor = ultrasonic(app.a,'D7','D6','OutputFormat','double');
app.servo_motor = servo(app.a,'D5');
app.kk=1;
app.table = zeros(180,2);
app.Paxx = polaraxes(app.SonarpomocultrazvukovhosenzoruPanel);
app.Image.Visible= 'off';
        end

        % Button pushed function: SPUSTITButton
        function SPUSTITButtonPushed(app, event)


while true 
    
p = 1;
app.table = zeros(180,2);
for angle = 0 : 1/180 : 1

    writePosition(app.servo_motor, angle);
    distance = readDistance(app.ultrasonic_sensor);
    app.table(p,1) = (p-1);
    app.table(p,2) = round(distance * 100,2);
    if app.table(p,2)>=100
        app.table(p,2)=100;
    end
    p = p + 1;

  app.kk=app.table(:,2)<=30;
 
app.SonarpomocultrazvukovhosenzoruPanelSizeChanged();
end

q = 1;
for angle = 1 : -1/180 : 0

    writePosition(app.servo_motor, angle);
    distance = readDistance(app.ultrasonic_sensor);
    app.table(p-q,2) = (app.table(p-q,2) + round(distance * 100,2))/2;
     if app.table(p-q,2)>=100
        app.table(p-q,2)=100;
    end
    q = q + 1;
   
app.kk=app.table(:,2)<=30;
app.SonarpomocultrazvukovhosenzoruPanelSizeChanged();
end

end

        end

        % Button pushed function: KONECButton
        function KONECButtonPushed(app, event)
         close(app.UIFigure);
         close all;
        end

        % Size changed function: SonarpomocultrazvukovhosenzoruPanel
        function SonarpomocultrazvukovhosenzoruPanelSizeChanged(app, event)
%             position = app.SonarpomocultrazvukovhosenzoruPanel.Position;
polarplot (app.Paxx, app.table(:,1)*pi/180, app.table (:,2));
hold (app.Paxx,"on") 
polarplot (app.Paxx,app.table(app.kk,1)*pi/180, app.table (app.kk,2),'Color','r');
thetalim(app.Paxx,[0 180]);
rlim(app.Paxx,[0 100])
hold (app.Paxx,'off')
        end

        % Button pushed function: ULOITDATAButton
        function ULOITDATAButtonPushed(app, event)
            writematrix(app.table,"ulozeni_dat_ze_sonaru.txt")
            
            exportgraphics(app.Paxx,'ulozeni_obrazku_plotu.png')
        end

        % Callback function: NATIDATAButton, UITable
        function natidataButtonPushed(app, event)
            
           app.table1= readmatrix("ulozeni_dat_ze_sonaru.txt");
           app.Novyobrazek =imread('ulozeni_obrazku_plotu.png'); 
           app.Image.ImageSource= (app.Novyobrazek);
           app.Image.Visible= 'on';
           app.UITable.Data = app.table1;
                      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SPUSTITButton = uibutton(app.UIFigure, 'push');
            app.SPUSTITButton.ButtonPushedFcn = createCallbackFcn(app, @SPUSTITButtonPushed, true);
            app.SPUSTITButton.Text = {'SPUSTIT'; ''};
            app.ULOITDATAButton = uibutton(app.UIFigure, 'push');
            app.ULOITDATAButton.ButtonPushedFcn = createCallbackFcn(app, @ULOITDATAButtonPushed, true);
            app.ULOITDATAButton.Text = {'ULOŽIT DATA'; ''};
            app.NATIDATAButton = uibutton(app.UIFigure, 'push');
            app.NATIDATAButton.ButtonPushedFcn = createCallbackFcn(app, @natidataButtonPushed, true);
            app.NATIDATAButton.Text = {'NAČTI DATA'; ''; ''};
            app.KONECButton = uibutton(app.UIFigure, 'push');
            app.KONECButton.ButtonPushedFcn = createCallbackFcn(app, @KONECButtonPushed, true);
            app.KONECButton.Text = 'KONEC';
            app.SonarpomocultrazvukovhosenzoruPanel = uipanel(app.UIFigure);
            app.SonarpomocultrazvukovhosenzoruPanel.Title = 'Sonar - pomocí ultrazvukového senzoru ';
            app.SonarpomocultrazvukovhosenzoruPanel.SizeChangedFcn = createCallbackFcn(app, @SonarpomocultrazvukovhosenzoruPanelSizeChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'stupně'; 'vzdálenost v cm'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @natidataButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SEMESTRALNI_PRACE_NA_MATLAB_Dominik_Tomasek

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end